classdef SNR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        csvName             matlab.ui.control.Label
        wavName             matlab.ui.control.Label
        CalculateSNRButton  matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
        BackButton          matlab.ui.control.Button
        specPlot            matlab.ui.control.UIAxes
        overlayPlot         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        wav_directory = uigetdir(pwd, "Select folder containing wav files.")
        csv_directory = uigetdir(pwd, "Select folder containing csv files.")
        matchesDirectory = uigetdir(pwd, "Select directory that will store file matches and snr.");

        matchNo = 0;
        noOfMatches = 0;
    end
    
    methods (Access = private)
        
        function plotGraphs(app)
            file_name = 'Match_Results.csv';
            match_file = fullfile(app.matchesDirectory, file_name);
            match_table = readtable(match_file,"ReadVariableNames", true);
            app.noOfMatches = height(match_table);

            if (app.matchNo > app.noOfMatches)
                msgbox("No more whistles to display.")
            else
                row = match_table(app.matchNo,:);
                % Get file paths.
                wavPath = fullfile(app.wav_directory, strcat(row.("WAV"), ".wav"));
                csvPath = fullfile(app.csv_directory, strcat("pc_", strcat(row.("CSV"), ".csv")));

                app.csvName.Text = csvPath;
                drawnow
                app.wavName.Text = wavPath;
                drawnow
    
                if (contains(row.("CSV"), "No matches"))
                    app.matchNo = app.matchNo + 1;
                    plotGraphs(app)
                else
                    % Plot spectrogram
                    [s, f, t] = plotSpectrogram(wavPath);
                    imagesc(app.specPlot, t, flipud(f), rot90(log(abs(s'))));
                    set(app.specPlot, 'YDir', 'normal')
    
                    % Plot contour overlay
                    imagesc(app.overlayPlot, t, flipud(f), rot90(log(abs(s'))));
                    set(app.overlayPlot, 'YDir', 'normal')
                    hold(app.overlayPlot, "on")
                    time = extractBetween(row.("WAV"), 1, 15);
                    T = datetime(time,'InputFormat','yyyyMMdd_HHmmss');
                    time_init = posixtime(T);
                    freqTable = readtable(csvPath, "ReadVariableNames",true);
                    
                    frequency = freqTable.("PeakFrequency_Hz_");
                    time_ms = (freqTable.("Time_ms_") - time_init*1000) / 1000;
                    
                    plot(app.overlayPlot, time_ms, frequency);

                    % Plot box overlay
                    [~, min_time, max_time, min_frequency, max_frequency] = computeSNR(csvPath, wavPath);
                    plot (app.overlayPlot, [min_time, min_time], [min_frequency, max_frequency])
                    plot (app.overlayPlot, [max_time, max_time], [min_frequency, max_frequency])
                    plot (app.overlayPlot, [min_time, max_time], [min_frequency, min_frequency])
                    plot (app.overlayPlot, [min_time, max_time], [max_frequency, max_frequency])

                    hold(app.overlayPlot, "off")
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            findMatches(app.wav_directory, app.csv_directory, app.matchesDirectory);
            % Make snr results file.
            file_name = 'SNR_Results.csv';
            snr_file = fullfile(app.matchesDirectory, file_name);
            writelines("Clip,SNR", snr_file, "WriteMode","overwrite");
        end

        % Button pushed function: NextButton
        function nextWhistle(app, event)
            if (app.matchNo > app.noOfMatches)
                msgbox("Out of bounds whistle.")
                disp(app.noOfMatches)
                % Plot current overlay and whistle.
                plotGraphs(app)
            else
                app.matchNo = app.matchNo + 1;
                % Plot overlay and whistle
                plotGraphs(app)
            end
        end

        % Button pushed function: BackButton
        function previousWhistle(app, event)
            if (app.matchNo < 1)
                msgbox("Out of bounds whistle.")
                % Plot current overlay and whistle.
                plotGraphs(app)
            else
                app.matchNo = app.matchNo - 1;
                % Plot overlay and whistle
                plotGraphs(app)
            end
        end

        % Button pushed function: CalculateSNRButton
        function writeSNR(app, event)
            % Getting files.
            file_name = 'Match_Results.csv';
            match_file = fullfile(app.matchesDirectory, file_name);
            match_table = readtable(match_file,"ReadVariableNames", true);
            row = match_table(app.matchNo,:);
            % Get file paths.
            wavPath = fullfile(app.wav_directory, strcat(row.("WAV"), ".wav"));
            csvPath = fullfile(app.csv_directory, strcat("pc_", strcat(row.("CSV"), ".csv")));

            [snrVal, ~, ~, ~, ~] = computeSNR(csvPath, wavPath);
            file_name = 'SNR_Results.csv';
            snr_file = fullfile(app.matchesDirectory, file_name);

            wavName = row.("WAV");
            writelines(strcat(wavName, strcat(",", string(snrVal))), snr_file, "WriteMode","append");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.overlayPlot = uiaxes(app.UIFigure);
            title(app.overlayPlot, 'Overlay')
            xlabel(app.overlayPlot, 'X')
            ylabel(app.overlayPlot, 'Y')
            zlabel(app.overlayPlot, 'Z')
            app.specPlot = uiaxes(app.UIFigure);
            title(app.specPlot, 'Clip')
            xlabel(app.specPlot, 'X')
            ylabel(app.specPlot, 'Y')
            zlabel(app.specPlot, 'Z')
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @previousWhistle, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @nextWhistle, true);
            app.NextButton.Text = 'Next';
            app.CalculateSNRButton = uibutton(app.UIFigure, 'push');
            app.CalculateSNRButton.ButtonPushedFcn = createCallbackFcn(app, @writeSNR, true);
            app.CalculateSNRButton.Text = 'Calculate SNR';
            app.wavName = uilabel(app.UIFigure);
            app.wavName.Text = 'WAV File';
            app.csvName = uilabel(app.UIFigure);
            app.csvName.Text = 'CSV File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNR_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end